classdef app1_converted2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        NoteLabel          matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        HzLabel            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        LeftPanel          matlab.ui.container.Panel
        MicrophoneButton   matlab.ui.control.StateButton
        Label_3            matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        UploadSoundButton  matlab.ui.control.Button
        PlotgraphButton    matlab.ui.control.Button
    end

    
    properties (Access = public)
        music % Description
        maxXValue % Description
        arduinoObj
        data = []
    end


    methods (Access = private)
        
        function readsound(app,src,~)
            x = readline(src);
            app.Label.Text = x
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadSoundButton
        function UploadSoundButtonPushed(app, event)
          [filename ,path]=uigetfile(...
          {'*.mp3;*.m4a;*.wav;*.aac;*.flac',...
          'Sound Files (*.mp3,*.m4a,*.wav,*.aac,*.flac)';
          '*.*',  'All Files (*.*)'}, ...
          'Select a File');
          app.music = [path filename];
          app.TextArea.Value=filename;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Callback function: PlotgraphButton, UIAxes
        function PlotgraphButtonPushed(app, event)
          [X, Fs] = audioread(app.music);
          X = X(:,1);
          N = numel(X);
          T  = 1/Fs;           
          deltaF = Fs/N;  
          Y = fft(X);
          Y = abs(Y/N);
          Amp = [Y(ceil(end/2)+1:end)' Y(1) Y(2:ceil(end/2))']';
          if (mod(N,2) == 0)
          sampleIndex = -N/2:1:N/2-1; 
          else
          sampleIndex = -(N-1)/2:1:(N-1)/2; 
          end
          AmpSquared = Amp.^2;
          xIndex = find(AmpSquared == max(AmpSquared), 1, 'first');
          app.maxXValue = abs(deltaF*sampleIndex(xIndex));
          a =  num2str(app.maxXValue,'%.2f')
          
          if  (20.025 <= app.maxXValue) && (app.maxXValue < 21.215)
            app.Label.Text = 'E0'
          elseif  (21.215 <= app.maxXValue) && (app.maxXValue < 22.475)
            app.Label.Text = 'F0'
          elseif  (22.475 <= app.maxXValue) && (app.maxXValue < 23.81)
            app.Label.Text = 'F#0/Gb0'
          elseif  (23.81 <= app.maxXValue) && (app.maxXValue < 25.23)
            app.Label.Text = 'G0'
          elseif  (25.23 <= app.maxXValue) && (app.maxXValue < 26.73)
            app.Label.Text = 'G#0/Ab0'
          elseif  (26.73 <= app.maxXValue) && (app.maxXValue < 28.32)
            app.Label.Text = 'A0'
          elseif  (28.32 <= app.maxXValue) && (app.maxXValue < 30.005)
            app.Label.Text = 'A#0/Bb0'
          elseif  (30.005 <= app.maxXValue) && (app.maxXValue < 31.785)
            app.Label.Text = 'B0'
          elseif  (31.785 <= app.maxXValue) && (app.maxXValue < 33.675)
            app.Label.Text = 'C1'
          elseif  (33.675 <= app.maxXValue) && (app.maxXValue < 35.68)
            app.Label.Text = ' C#1/Db1'
          elseif  (35.68 <= app.maxXValue) && (app.maxXValue < 37.8)
            app.Label.Text = 'D1'
          elseif  (37.8 <= app.maxXValue) && (app.maxXValue < 40.045)
            app.Label.Text = 'D#1/Eb1'
          elseif  (40.045 <= app.maxXValue) && (app.maxXValue < 42.425)
            app.Label.Text = 'E1'
          elseif  (42.425 <= app.maxXValue) && (app.maxXValue < 44.95)
            app.Label.Text = 'F1'
          elseif  (44.95<= app.maxXValue) && (app.maxXValue < 47.625)
            app.Label.Text = 'F#1/Gb1'
          elseif  (47.625 <= app.maxXValue) && (app.maxXValue < 50.455)
            app.Label.Text = 'G1'
          elseif  (50.455 <= app.maxXValue) && (app.maxXValue < 53.455)
            app.Label.Text = 'G#1/Ab1'
          elseif  (53.455 <= app.maxXValue) && (app.maxXValue < 56.635)
            app.Label.Text = 'A1'
          elseif  (56.635 <= app.maxXValue) && (app.maxXValue < 60.005)
            app.Label.Text = 'A#1/Bb1'
          elseif  (60.005 <= app.maxXValue) && (app.maxXValue < 63.575)
            app.Label.Text = 'B1'
          elseif  (63.575 <= app.maxXValue) && (app.maxXValue < 67.355)
            app.Label.Text = 'C2'
          elseif  (67.355 <= app.maxXValue) && (app.maxXValue < 71.36)
            app.Label.Text = ' C#2/Db2'
          elseif  (71.36 <= app.maxXValue) && (app.maxXValue < 75.6)
            app.Label.Text = 'D2'
          elseif  (75.6 <= app.maxXValue) && (app.maxXValue < 80.095)
            app.Label.Text = 'D#2/Eb2'
          elseif  (80.095 <= app.maxXValue) && (app.maxXValue < 84.86)
            app.Label.Text = 'E2'
          elseif  (84.86 <= app.maxXValue) && (app.maxXValue < 89.905)
            app.Label.Text = 'F2'
          elseif  (89.905 <= app.maxXValue) && (app.maxXValue < 95.25)
            app.Label.Text = 'F#2/Gb2'
          elseif  (95.25 <= app.maxXValue) && (app.maxXValue < 100.915)
            app.Label.Text = 'G2'
          elseif  (100.915 <= app.maxXValue) && (app.maxXValue < 106.915)
            app.Label.Text = 'G#2/Ab2'
          elseif  (106.915 <= app.maxXValue) && (app.maxXValue < 113.27)
            app.Label.Text = 'A2'
          elseif  (113.27 <= app.maxXValue) && (app.maxXValue < 120.005)
            app.Label.Text = 'A#2/Bb2'
          elseif  (120.005 <= app.maxXValue) && (app.maxXValue < 127.14)
            app.Label.Text = 'B2'
          elseif  (127.14 <= app.maxXValue) && (app.maxXValue < 134.7)
            app.Label.Text = 'C3'
          elseif  (134.7 <= app.maxXValue) && (app.maxXValue < 142.71)
            app.Label.Text = ' C#3/Db3'
          elseif  (142.71 <= app.maxXValue) && (app.maxXValue < 151.195)
            app.Label.Text = 'D3'
          elseif  (151.195 <= app.maxXValue) && (app.maxXValue < 160.185)
            app.Label.Text = 'D#3/Eb3'
          elseif  (160.185 <= app.maxXValue) && (app.maxXValue < 169.71)
            app.Label.Text = 'E3'
          elseif  (169.71 <= app.maxXValue) && (app.maxXValue < 179.805)
            app.Label.Text = 'F3'
          elseif  (179.805 <= app.maxXValue) && (app.maxXValue < 190.5)
            app.Label.Text = 'F#3/Gb3'
          elseif  (190.5 <= app.maxXValue) && (app.maxXValue < 201.825)
            app.Label.Text = 'G3'
          elseif  (201.825 <= app.maxXValue) && (app.maxXValue < 213.825)
            app.Label.Text = 'G#3/Ab3'
          elseif  (213.825 <= app.maxXValue) && (app.maxXValue < 226.54)
            app.Label.Text = 'A3'
          elseif  (226.54 <= app.maxXValue) && (app.maxXValue < 240.01)
            app.Label.Text = 'A#3/Bb3'
          elseif  (240.01 <= app.maxXValue) && (app.maxXValue < 254.285)
            app.Label.Text = 'B3'
          elseif  (254.285 <= app.maxXValue) && (app.maxXValue < 269.405)
            app.Label.Text = 'C4'
          elseif  (269.405 <= app.maxXValue) && (app.maxXValue < 285.42)
            app.Label.Text = ' C#4/Db4'
          elseif  (285.42 <= app.maxXValue) && (app.maxXValue < 302.395)
            app.Label.Text = 'D4'
          elseif  (302.395 <= app.maxXValue) && (app.maxXValue < 320.38)
            app.Label.Text = 'D#4/Eb4'
          elseif  (320.38 <= app.maxXValue) && (app.maxXValue < 339.43)
            app.Label.Text = 'E4'
          elseif  (339.43 <= app.maxXValue) && (app.maxXValue < 359.61)
            app.Label.Text = 'F4'
          elseif  (359.61 <= app.maxXValue) && (app.maxXValue < 380.995)
            app.Label.Text = 'F#4/Gb4'
          elseif  (380.995 <= app.maxXValue) && (app.maxXValue < 403.65)
            app.Label.Text = 'G4'
          elseif  (403.65 <= app.maxXValue) && (app.maxXValue < 427.65)
            app.Label.Text = 'G#4/Ab4'
          elseif  (427.65 <= app.maxXValue) && (app.maxXValue < 453.08)
            app.Label.Text = 'A4'
          elseif  (453.08 <= app.maxXValue) && (app.maxXValue < 480.02)
            app.Label.Text = 'A#4/Bb4'
          elseif  (480.02 <= app.maxXValue) && (app.maxXValue < 508.565)
            app.Label.Text = 'B4'
          elseif  (508.565 <= app.maxXValue) && (app.maxXValue < 538.81)
            app.Label.Text = 'C5'
          elseif  (538.81 <= app.maxXValue) && (app.maxXValue < 570.85)
            app.Label.Text = ' C#5/Db5'
          elseif  (570.85 <= app.maxXValue) && (app.maxXValue < 604.79)
            app.Label.Text = 'D5'
          elseif  (604.79 <= app.maxXValue) && (app.maxXValue < 640.75)
            app.Label.Text = 'D#5/Eb5'
          elseif  (640.75 <= app.maxXValue) && (app.maxXValue < 678.855)
            app.Label.Text = 'E5'
          elseif  (678.855 <= app.maxXValue) && (app.maxXValue < 719.225)
            app.Label.Text = 'F5'
          elseif  (719.225 <= app.maxXValue) && (app.maxXValue < 761.99)
            app.Label.Text = 'F#5/Gb5'
          elseif  (761.99 <= app.maxXValue) && (app.maxXValue < 807.3)
            app.Label.Text = 'G5'
          elseif  (807.3 <= app.maxXValue) && (app.maxXValue < 855.305)
            app.Label.Text = 'G#5/Ab5'
          elseif  (855.305 <= app.maxXValue) && (app.maxXValue < 906.165)
            app.Label.Text = 'A5'
          elseif  (906.165<= app.maxXValue) && (app.maxXValue < 960.05)
            app.Label.Text = 'A#5/Bb5'
          elseif  (960.05 <= app.maxXValue) && (app.maxXValue < 1017.135)
            app.Label.Text = 'B5'
          elseif  (1017.135 <= app.maxXValue) && (app.maxXValue < 1077.615)
            app.Label.Text = 'C6'
          elseif  (1077.615 <= app.maxXValue) && (app.maxXValue < 1141.695)
            app.Label.Text = ' C#6/Db6'
          elseif  (1141.695 <= app.maxXValue) && (app.maxXValue < 1209.585)
            app.Label.Text = 'D6'
          elseif  (1209.585 <= app.maxXValue) && (app.maxXValue < 1281.51)
            app.Label.Text = 'D#6/Eb6'
          elseif  (1281.51 <= app.maxXValue) && (app.maxXValue < 1357.71)
            app.Label.Text = 'E6'
          elseif  (1357.71 <= app.maxXValue) && (app.maxXValue < 1438.445)
            app.Label.Text = 'F6'
          elseif  (1438.445 <= app.maxXValue) && (app.maxXValue < 1523.98)
            app.Label.Text = 'F#6/Gb6'
          elseif  (1523.98 <= app.maxXValue) && (app.maxXValue < 1614.6)
            app.Label.Text = 'G6'
          elseif  (1614.6 <= app.maxXValue) && (app.maxXValue < 1710.61)
            app.Label.Text = 'G#6/Ab6'
          elseif  (1710.61 <= app.maxXValue) && (app.maxXValue < 1812.33)
            app.Label.Text = 'A6'
          elseif  (1812.33 <= app.maxXValue) && (app.maxXValue < 1920.095)
            app.Label.Text = 'A#6/Bb6'
          elseif  (1920.095 <= app.maxXValue) && (app.maxXValue < 2034.265)
            app.Label.Text = 'B6'
          else
            app.Label.Text = "Can't find" 
          end
          app.Label_2.Text = a
          plot(app.UIAxes,deltaF*sampleIndex, AmpSquared);
          axis(app.UIAxes,[0 2000 0 inf]);


        end

        % Value changed function: MicrophoneButton
        function MicrophoneButtonValueChanged(app, event)
            if app.MicrophoneButton.Value == true
                cla(app.UIAxes) ;
                app.Label_2.Text='';
                app.Label.Text='';
                app.arduinoObj = serialport('COM4',9600)
                configureTerminator(app.arduinoObj,"CR/LF");
                flush(app.arduinoObj);
                configureCallback(app.arduinoObj,"terminator",@(src, event) readsound(app,src,event));
            else
                app.arduinoObj = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.PlotgraphButton = uibutton(app.LeftPanel, 'push');
            app.PlotgraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.PlotgraphButton.Text = 'Plot graph';
            app.UploadSoundButton = uibutton(app.LeftPanel, 'push');
            app.UploadSoundButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSoundButtonPushed, true);
            app.UploadSoundButton.Text = 'Upload Sound';
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label_3 = uilabel(app.LeftPanel);
            app.MicrophoneButton = uibutton(app.LeftPanel, 'state');
            app.MicrophoneButton.ValueChangedFcn = createCallbackFcn(app, @MicrophoneButtonValueChanged, true);
            app.MicrophoneButton.Text = 'Microphone';
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Power of FFT of Wave (Audible Range = 20-20\times10^4 Hz)')
            xlabel(app.UIAxes, 'Frequency [Hz]')
            ylabel(app.UIAxes, '|FFT(Wave)|^2')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotgraphButtonPushed, true);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.HzLabel = uilabel(app.RightPanel);
            app.HzLabel.Text = 'Hz';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.NoteLabel = uilabel(app.RightPanel);
            app.NoteLabel.Text = 'Note :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_converted2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end